classdef myclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        Lamp               matlab.ui.control.Lamp
        RunButton          matlab.ui.control.StateButton
        TextArea           matlab.ui.control.TextArea
        featparamDropDown  matlab.ui.control.DropDown
        LoadButton         matlab.ui.control.Button
        Switch             matlab.ui.control.ToggleSwitch
        ListBox            matlab.ui.control.ListBox
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
    end


    properties (Access = public)
        allfiles  % Description
    end

    properties (Access = private)
        button % Description
        filename % Description
        pathname % Description
    end

%     methods (Access = private)
%     
%         function [BW,maskedRGBImage] = createMask(RGB)
%             % Convert RGB image to chosen color space
%             I = rgb2lab(RGB);
%             
%             % Define thresholds for channel 1 based on histogram settings
%             channel1Min = 5.900;
%             channel1Max = 97.668;
%             
%             % Define thresholds for channel 2 based on histogram settings
%             channel2Min = -21.278;
%             channel2Max = 40.514;
%             
%             % Define thresholds for channel 3 based on histogram settings
%             channel3Min = -27.206;
%             channel3Max = 91.934;
%             
%             % Create mask based on chosen histogram thresholds
%             sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
%                 (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
%                 (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
%             BW = sliderBW;
%             
%             % Initialize output masked image based on input image.
%             maskedRGBImage = RGB;
%             
%             % Set background pixels where BW is false to zero.
%             maskedRGBImage(repmat(~BW,[1 1 3])) = 0;
%         end
%         % normal3 is function to normalize image by the mean of mean and std of std of all the image
% %         for the datset
%         function normal3_results = normal3(im)
%                 norms = load('dynamic_range2.mat');
%                 ltarmean = mean([norms.result.redChannel_Mean]);
%                 atarmean = mean([norms.result.greenChannel_Mean]);
%                 btarmean = mean([norms.result.blueChannel_Mean]);
%                 
%                 ltarstd = std([norms.result.redChannel_std]);
%                 atarstd = std([norms.result.greenChannel_std]);
%                 btarstd = std([norms.result.blueChannel_std]);
%                 
%                 % ltarmean = mean2(target(:,:,1));
%                 % atarmean = mean2(target(:,:,2));
%                 % btarmean = mean2(target(:,:,3));
%                 % ltarstd = std2(target(:,:,1));
%                 % atarstd = std2(target(:,:,2));
%                 % btarstd = std2(target(:,:,3));
%                 %%
%                 image = rgb2lab(im);
%                 lorigmean = mean2(image(:,:,1));
%                 aorigmean = mean2(image(:,:,2));
%                 borigmean = mean2(image(:,:,3));
%                 lorigstd = std2(image(:,:,1));
%                 aorigstd = std2(image(:,:,2));
%                 borigstd = std2(image(:,:,3));
%                 lnew = (((image(:,:,1) - lorigmean)/lorigstd)*ltarstd)+ltarmean;
%                 anew = (((image(:,:,2) - aorigmean)/aorigstd)*atarstd)+atarmean;
%                 bnew = (((image(:,:,3) - borigmean)/borigstd)*btarstd)+btarmean;
%                 img(:,:,1) = lnew;
%                 img(:,:,2) = anew;
%                 img(:,:,3) = bnew;
%                 normimg = lab2rgb(img);
%                 
%                 normal3_results.img = normimg;
%        end
%         
%        function  enhance_results = enhance(im)
%                 % Replace the sample below with your code----------------------------------
%                 srgb2lab = makecform('srgb2lab');
%                 lab2srgb = makecform('lab2srgb');
%                 % convert to L*a*b*
%                 img_lab = applycform(im, srgb2lab); 
%                 
%                 % the values of luminosity can span a range from 0 to 100; scale them
%                 % to [0 1] range (appropriate for MATLAB(R) intensity images of class double) 
%                 % before applying the three contrast enhancement techniques
%                 
%                 max_luminosity = 100;
%                 L = img_lab(:,:,1)/max_luminosity;
%                 
%                 % replace the luminosity layer with the processed data and then convert
%                 % the image back to the RGB colorspace
%                 img_imadjust = img_lab;
%                 img_imadjust(:,:,1) = imadjust(L)*max_luminosity;
%                 img_imadjust = applycform(img_imadjust, lab2srgb);
%                 enhance_results.img_imadjust = img_imadjust;
%                 
%                 img_histeq = img_lab;
%                 img_histeq(:,:,1) = histeq(L)*max_luminosity;
%                 img_histeq = applycform(img_histeq, lab2srgb);
%                 enhance_results.img_histeq = img_histeq;
%                 
%                 img_adapthisteq = img_lab;
%                 img_adapthisteq(:,:,1) = adapthisteq(L)*max_luminosity;
%                 img_adapthisteq = applycform(img_adapthisteq, lab2srgb);
%                 enhance_results.img_adapthisteq = img_adapthisteq;
%        end
%         
%                
%     end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            promptMessage = sprintf('Which dataset do you want to use?');
            app.button = questdlg(promptMessage, 'Dataset', 'Dataset 1', 'Dataset 2',...
                                'Dataset 3', 'Cancel');
            if strcmp(app.button, 'Cancel')
                return;
            end
            
            
            [~, app.pathname]  = uigetfile('*.*', 'Specify an image file'); 
            app.allfiles = dir( fullfile(app.pathname,'*.png') );%# list all *.xyz files
            app.allfiles = {app.allfiles.name}'; %'# file names   
            
            
            img_combined = [];
            Table = [];
            switch app.button
            % Separate dataset 1 into training and testing set
                   case 'Dataset 1'        
                   % reshape cell into 100 by 3 images
                   newshape = reshape(app.allfiles,[100,3]);
                   % Assign 15% of the data as testing set
                   testset = newshape(1:15, :);
                   % Assign 85% of data as testing set    
                   trainset = newshape(16:end,:);
        
            % Separate dataset 2 into training and testing set
                   case 'Dataset 2'
                   % reshape cell into 100 by 16 images
                   newshape = reshape(app.allfiles,[16,100]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:15);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,16:end);
            % Separate dataset 3 into training and testing set
                   case 'Dataset 3'
                   % reshape cell into 59 by 16 images
                   newshape = reshape(app.allfiles,[16,59]);
                   % Assign 15% of the data as testing set
                   testset = newshape(:,1:9);
                   % Assign 85% of data as testing set    
                   trainset = newshape(:,10:end);
             end 
            % 
            %                     image1 = imread(app.allfiles);
            %                     imshow(image1, 'Parent', app.UIAxes);
            %                     app.TextArea.Value = string(trainset);
            
            app.RunButton.Enable = 'on';
            app.ListBox.Enable = 'on';
            app.ListBox.Items =  app.allfiles;    
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
          
            if  isequal(value,'Train')
                app.Lamp.Enable = 'on';
                app.ListBox.Enable = 'off';
            elseif isequal(value,'Test')
                app.Lamp.Enable = 'off';
                app.ListBox.Enable = 'on';
            end
            %             app.LoadButton.Enable = 'on';
           
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            global image1 image2 image3
            
            
            app.ListBox.Enable = 'on';
            app.ListBox.Items =  app.allfiles;
            
            % Define value as what use selects from the listbox
            value = app.ListBox.Value;
            
            % Display raw image in top left figure
            image1 = imread(strcat(app.pathname,value));            
            imshow(image1, 'Parent', app.UIAxes);
            
%             Display Normalized image in the top right figure
                        image2 = normal3(image1);
                        image3 = enhance(image2.img);
                        imshow(image3.img_histeq, 'Parent', app.UIAxes_2);
                        
                        [BW,maskedRGBImage] = createMask(image3.img_histeq);
                        imshow(BW, 'Parent', app.UIAxes_3);
                        imshow(maskedRGBImage, 'Parent', app.UIAxes_4);

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.TextArea.Value = value;
                         app.RunButton.Enable = 'on';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.featparamDropDown = uidropdown(app.UIFigure);
            app.featparamDropDown.Enable = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Test', 'Train'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Test';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = {};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Nomalized Image');
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Segmented Image_ BW');
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Segmented Image masked RGBImage');
        end
    end

    methods (Access = public)

        % Construct app
        function app = myclassifier()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end